classdef MorphingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ExecuteMenu                     matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralDataTab                  matlab.ui.container.Tab
        DateInformationPanel            matlab.ui.container.Panel
        AddherethestartdarteofthebaselinedataLabel  matlab.ui.control.Label
        AddheretheenddateofthebaselinedataLabel  matlab.ui.control.Label
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        globalradiationandwindspeeddataLabel  matlab.ui.control.Label
        ClimateChangeScenarioPanel      matlab.ui.container.Panel
        SelecttheusedclimatechangescenarioLabel  matlab.ui.control.Label
        ClimatechangescenarioDropDownLabel  matlab.ui.control.Label
        ClimatechangescenarioDropDown   matlab.ui.control.DropDown
        LocationDataPanel               matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        AddthisinformationindegreesLabel  matlab.ui.control.Label
        AddthisinformationindegreesLabel_2  matlab.ui.control.Label
        AddalsospacialerrorindegreestoincludemoredatapointsPanel  matlab.ui.container.Panel
        SpacialerrorEditFieldLabel      matlab.ui.control.Label
        SpacialerrorEditField           matlab.ui.control.NumericEditField
        AddthespacialerrorindegreesLabel  matlab.ui.control.Label
        AddLocationNamePanel            matlab.ui.container.Panel
        LocationNameEditFieldLabel      matlab.ui.control.Label
        LocationNameEditField           matlab.ui.control.EditField
        BaselineDataTab                 matlab.ui.container.Tab
        BaselineTemperaturePanel        matlab.ui.container.Panel
        BaselineTemperaturefilelocationEditFieldLabel  matlab.ui.control.Label
        BaselineTemperaturefilelocationEditField  matlab.ui.control.EditField
        LoadFileButton                  matlab.ui.control.Button
        UsethesamebaselinedataforallvariablesCheckBox  matlab.ui.control.CheckBox
        BaselineGlobalRadiationPanel    matlab.ui.container.Panel
        BaselineGlobalRadiationFileEditFieldLabel  matlab.ui.control.Label
        BaselineGlobalRadiationFileEditField  matlab.ui.control.EditField
        LoadFileButton_2                matlab.ui.control.Button
        BaselineWindSpeedPanel          matlab.ui.container.Panel
        BaselineWindSpeedFileEditFieldLabel  matlab.ui.control.Label
        BaselineWindSpeedFileEditField  matlab.ui.control.EditField
        LoadFileButton_3                matlab.ui.control.Button
        ClimateChangeDataTab            matlab.ui.container.Tab
        TemperaturePanel                matlab.ui.container.Panel
        MorphingOptionsPanel            matlab.ui.container.Panel
        SelectmorphingmethodforTemperaturefileButtonGroup  matlab.ui.container.ButtonGroup
        ShiftButton                     matlab.ui.control.RadioButton
        StretchButton                   matlab.ui.control.RadioButton
        ShiftandstretchButton           matlab.ui.control.RadioButton
        SelecttypeofusedclimatechangedataButtonGroup  matlab.ui.container.ButtonGroup
        OnlymonthlyButton               matlab.ui.control.RadioButton
        StdDailyButton                  matlab.ui.control.RadioButton
        MaxMinButton                    matlab.ui.control.RadioButton
        SelectClimateChangeDataforTemperaturemorphingPanel  matlab.ui.container.Panel
        MonthlyChangeDataFileEditFieldLabel  matlab.ui.control.Label
        MonthlyChangeDataFileEditField  matlab.ui.control.EditField
        LoadFileButton_4                matlab.ui.control.Button
        DataBaseTypeDropDownLabel       matlab.ui.control.Label
        DataBaseTypeDropDown            matlab.ui.control.DropDown
        FutureDailyMeantemperatureDatafileEditFieldLabel  matlab.ui.control.Label
        FutureDailyMeantemperatureDatafileEditField  matlab.ui.control.EditField
        LoadFileButton_5                matlab.ui.control.Button
        FutureDailyMaxtemperatureDatafileEditFieldLabel  matlab.ui.control.Label
        FutureDailyMaxtemperatureDatafileEditField  matlab.ui.control.EditField
        LoadFileButton_6                matlab.ui.control.Button
        FutureDailyMintemperatureDatafileEditFieldLabel  matlab.ui.control.Label
        FutureDailyMintemperatureDatafileEditField  matlab.ui.control.EditField
        LoadFileButton_7                matlab.ui.control.Button
        GlobalRadiationWindSpeedPanel   matlab.ui.container.Panel
        MorphingOptionsPanel_2          matlab.ui.container.Panel
        SelectmorphingmethodforGlobalRadiationfileButtonGroup  matlab.ui.container.ButtonGroup
        StretchButton_3                 matlab.ui.control.RadioButton
        SelectmorphingmethodforWindSpeedButtonGroup  matlab.ui.container.ButtonGroup
        StretchButton_2                 matlab.ui.control.RadioButton
        SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel  matlab.ui.container.Panel
        MonthlyGlobalRadiationChangeEditFieldLabel  matlab.ui.control.Label
        MonthlyGlobalRadiationChangeEditField  matlab.ui.control.EditField
        LoadFileButton_8                matlab.ui.control.Button
        MonthlyWindSpeedChangeEditFieldLabel  matlab.ui.control.Label
        MonthlyWindSpeedChangeEditField  matlab.ui.control.EditField
        LoadFileButton_9                matlab.ui.control.Button
        DataBaseTypeRadiationWindDropDownLabel  matlab.ui.control.Label
        DataBaseTypeRadiationWindDropDown  matlab.ui.control.DropDown
        ClimateChangeInfoTab            matlab.ui.container.Tab
        GeneralInfoOnUsedTimeperiodsPanel  matlab.ui.container.Panel
        AveragePeriodEditFieldLabel     matlab.ui.control.Label
        AveragePeriodEditField          matlab.ui.control.NumericEditField
        ReferenceYearEditFieldLabel     matlab.ui.control.Label
        ReferenceYearEditField          matlab.ui.control.NumericEditField
        MorphingYearEditFieldLabel      matlab.ui.control.Label
        MorphingYearEditField           matlab.ui.control.NumericEditField
        MonthlyTemperatureStartYearEditFieldLabel  matlab.ui.control.Label
        MonthlyTemperatureStartYearEditField  matlab.ui.control.NumericEditField
        DailyTemperatureStartYearEditFieldLabel  matlab.ui.control.Label
        DailyTemperatureStartYearEditField  matlab.ui.control.NumericEditField
        RadiationChangeStartYearEditFieldLabel  matlab.ui.control.Label
        RadiationChangeStartYearEditField  matlab.ui.control.NumericEditField
        WindChangeStartYearEditFieldLabel  matlab.ui.control.Label
        WindChangeStartYearEditField    matlab.ui.control.NumericEditField
        GeneralInfoforSavingDataPanel   matlab.ui.container.Panel
        Saveallvariableson1fileCheckBox  matlab.ui.control.CheckBox
        SavefilepathEditFieldLabel      matlab.ui.control.Label
        SavefilepathEditField           matlab.ui.control.EditField
        PathButton                      matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        TemperaturefilepathEditFieldLabel  matlab.ui.control.Label
        TemperaturefilepathEditField    matlab.ui.control.EditField
        PathButton_2                    matlab.ui.control.Button
        TemperaturefilenameEditFieldLabel  matlab.ui.control.Label
        TemperaturefilenameEditField    matlab.ui.control.EditField
        TypeDropDownLabel               matlab.ui.control.Label
        FileTypeDropDown                matlab.ui.control.DropDown
        TypeDropDown_2Label             matlab.ui.control.Label
        TemperatureFileTypeDropDown     matlab.ui.control.DropDown
        RadiationfilepathEditFieldLabel  matlab.ui.control.Label
        RadiationfilepathEditField      matlab.ui.control.EditField
        PathButton_3                    matlab.ui.control.Button
        RadiationfilenameEditFieldLabel  matlab.ui.control.Label
        RadiationfilenameEditField      matlab.ui.control.EditField
        TypeDropDown_3Label             matlab.ui.control.Label
        RadiationFileTypeDropDown       matlab.ui.control.DropDown
        WindfilepathEditFieldLabel      matlab.ui.control.Label
        WindfilepathEditField           matlab.ui.control.EditField
        PathButton_4                    matlab.ui.control.Button
        WindfilenameEditFieldLabel      matlab.ui.control.Label
        WindfilenameEditField           matlab.ui.control.EditField
        TypeDropDown_4Label             matlab.ui.control.Label
        WindFileTypeDropDown            matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [fileOT,pathOT] =uigetfile;
            app.BaselineTemperaturefilelocationEditField.Value = string(pathOT) + string(fileOT);
            if fileOT == 0 %app.BaselineTemperaturefilelocationEditField == 0
                app.BaselineTemperaturefilelocationEditField.Value = '';
            end
            if app.UsethesamebaselinedataforallvariablesCheckBox.Value == 1
                app.BaselineGlobalRadiationFileEditField.Value = string(pathOT) + string(fileOT);
                app.BaselineWindSpeedFileEditField.Value = string(pathOT) + string(fileOT);
            end
        end

        % Value changed function: 
        % UsethesamebaselinedataforallvariablesCheckBox
        function UsethesamebaselinedataforallvariablesCheckBoxValueChanged(app, event)
            value = app.UsethesamebaselinedataforallvariablesCheckBox.Value;
            if value == 1
                app.BaselineGlobalRadiationFileEditField.Value    = app.BaselineTemperaturefilelocationEditField.Value;
                app.BaselineWindSpeedFileEditField.Value      = app.BaselineTemperaturefilelocationEditField.Value;
                app.BaselineTemperaturefilelocationEditField.Enable = 'On';
                app.BaselineTemperaturefilelocationEditField.Editable = 'On';
                app.BaselineGlobalRadiationFileEditField.Enable = 'Off';
                app.BaselineGlobalRadiationFileEditField.Editable = 'Off';
                app.BaselineWindSpeedFileEditField.Enable = 'Off';
                app.BaselineWindSpeedFileEditField.Editable = 'Off';
            else
                % app.BaselineGlobalRadiationFileEditField    = '';
                % app.BaselineWindSpeedFileEditField          = '';
                app.BaselineTemperaturefilelocationEditField.Enable = 'On';
                app.BaselineTemperaturefilelocationEditField.Editable = 'On';
                app.BaselineGlobalRadiationFileEditField.Enable = 'On';
                app.BaselineGlobalRadiationFileEditField.Editable = 'On';
                app.BaselineWindSpeedFileEditField.Enable = 'On';
                app.BaselineWindSpeedFileEditField.Editable = 'On';
            end
        end

        % Button pushed function: LoadFileButton_2
        function LoadFileButton_2Pushed(app, event)
            [fileOR,pathOR] =uigetfile;
            app.BaselineGlobalRadiationFileEditField.Value = string(pathOR) + string(fileOR);
            if fileOR == 0 %app.BaselineGlobalRadiationFileEditField.Value == 0
                app.BaselineGlobalRadiationFileEditField.Value = '';
            end
        end

        % Button pushed function: LoadFileButton_3
        function LoadFileButton_3Pushed(app, event)
            [fileOW,pathOW] =uigetfile;
            app.BaselineWindSpeedFileEditField.Value = string(pathOW) + string(fileOW);
            if fileOW == 0 %app.BaselineWindSpeedFileEditField.Value == 0
                app.BaselineWindSpeedFileEditField.Value = '';
            end
        end

        % Selection changed function: 
        % SelecttypeofusedclimatechangedataButtonGroup
        function SelecttypeofusedclimatechangedataButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelecttypeofusedclimatechangedataButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Only monthly') && app.ShiftandstretchButton.Value == 1
                warndlg('Only monthly option cannot be selected if morphing method is Shift and strecth. Please select either StdDaily or MaxMin method depending on your data. If you only have monthly data, please select shifting as morphing method.', 'Wrong kind of datasets', 'OK');
            end
            if strcmp(selectedButton.Text, 'Only monthly')
                app.FutureDailyMeantemperatureDatafileEditField.Enable = 0;
                app.FutureDailyMaxtemperatureDatafileEditField.Enable = 0;
                app.FutureDailyMintemperatureDatafileEditField.Enable = 0;
            elseif strcmp(selectedButton.Text, 'StdDaily')
                app.FutureDailyMeantemperatureDatafileEditField.Enable = 1;
                app.FutureDailyMaxtemperatureDatafileEditField.Enable = 0;
                app.FutureDailyMintemperatureDatafileEditField.Enable = 0;
            elseif strcmp(selectedButton.Text, 'MaxMin')
                app.FutureDailyMeantemperatureDatafileEditField.Enable = 1;
                app.FutureDailyMaxtemperatureDatafileEditField.Enable = 1;
                app.FutureDailyMintemperatureDatafileEditField.Enable = 1;
            end
        end

        % Button pushed function: LoadFileButton_5
        function LoadFileButton_5Pushed(app, event)
            [fileDMean, pathDMean] = uigetfile;
            app.FutureDailyMeantemperatureDatafileEditField.Value = string(pathDMean) + string(fileDMean) ;
            if fileDMean == 0 %app.FutureDailyMeantemperatureDatafileEditField.Value == 0
                app.FutureDailyMeantemperatureDatafileEditField.Value = '';
            end
        end

        % Button pushed function: LoadFileButton_6
        function LoadFileButton_6Pushed(app, event)
            [fileDMax, pathDMax] = uigetfile;
            app.FutureDailyMaxtemperatureDatafileEditField.Value = string(pathDMax) + string(fileDMax);
            if fileDMax == 0 %app.FutureDailyMaxtemperatureDatafileEditField.Value == 0
                app.FutureDailyMaxtemperatureDatafileEditField.Value = '';
            end
        end

        % Button pushed function: LoadFileButton_7
        function LoadFileButton_7Pushed(app, event)
            [fileDMin, pathDMin] = uigetfile;
            app.FutureDailyMintemperatureDatafileEditField.Value = string(pathDMin) + string(fileDMin) ;
            if fileDMin == 0 %app.FutureDailyMintemperatureDatafileEditField.Value == 0
                app.FutureDailyMintemperatureDatafileEditField.Value = '';
            end
        end

        % Button pushed function: LoadFileButton_8
        function LoadFileButton_8Pushed(app, event)
            [fileMR, pathMR] = uigetfile;
            app.MonthlyGlobalRadiationChangeEditField.Value = string(pathMR) + string(fileMR);
            if fileMR == 0 %app.MonthlyGlobalRadiationChangeEditField.Value == 0
                app.MonthlyGlobalRadiationChangeEditField.Value = '';
            end
        end

        % Button pushed function: LoadFileButton_9
        function LoadFileButton_9Pushed(app, event)
            [fileMW, pathMW] = uigetfile;
            app.MonthlyWindSpeedChangeEditField.Value = string(pathMW) + string(fileMW);
            if fileMW == 0 %app.MonthlyWindSpeedChangeEditField.Value == 0
                app.MonthlyWindSpeedChangeEditField.Value = '';
            end
        end

        % Button pushed function: PathButton
        function PathButtonPushed(app, event)
            app.SavefilepathEditField.Value = uigetdir;
            if app.SavefilepathEditField.Value == 0
                app.SavefilepathEditField.Value = '';
            end
            if app.Saveallvariableson1fileCheckBox.Value == 1
                SavefilepathEditFieldValueChanged(app, event)
            end
        end

        % Value changed function: Saveallvariableson1fileCheckBox
        function Saveallvariableson1fileCheckBoxValueChanged(app, event)
            value = app.Saveallvariableson1fileCheckBox.Value;
            if value == 1
                
                app.TemperaturefilepathEditField.Value = app.SavefilepathEditField.Value;
                app.TemperaturefilenameEditField.Value = app.FileNameEditField.Value;
                app.RadiationfilepathEditField.Value  = app.SavefilepathEditField.Value ;
                app.RadiationfilenameEditField.Value  = app.FileNameEditField.Value;
                app.WindfilepathEditField.Value   = app.SavefilepathEditField.Value ;
                app.WindfilenameEditField.Value   = app.FileNameEditField.Value;
                
                app.SavefilepathEditField.Enable = 1;
                app.FileNameEditField.Enable = 1;
                app.TemperaturefilepathEditField.Enable = 0;
                app.TemperaturefilenameEditField.Enable = 0;
                app.RadiationfilepathEditField.Enable = 0;
                app.RadiationfilenameEditField.Enable = 0;
                app.WindfilepathEditField.Enable = 0;
                app.WindfilenameEditField.Enable = 0;
                
                app.TemperatureFileTypeDropDown.Value = app.FileTypeDropDown.Value;
                app.RadiationFileTypeDropDown.Value   = app.FileTypeDropDown.Value;
                app.WindFileTypeDropDown.Value     = app.FileTypeDropDown.Value;
            else
                app.SavefilepathEditField.Enable = 0;
                app.FileNameEditField.Enable = 0;
                app.TemperaturefilepathEditField.Enable = 1;
                app.TemperaturefilenameEditField.Enable = 1;
                app.RadiationfilepathEditField.Enable = 1;
                app.RadiationfilenameEditField.Enable = 1;
                app.WindfilepathEditField.Enable = 1;
                app.WindfilenameEditField.Enable = 1;
            end
        end

        % Button pushed function: PathButton_2
        function PathButton_2Pushed(app, event)
            app.TemperaturefilepathEditField.Value = uigetdir;
            if app.TemperaturefilepathEditField.Value == 0
                app.TemperaturefilepathEditField.Value = '';
            end
        end

        % Button pushed function: PathButton_3
        function PathButton_3Pushed(app, event)
            app.RadiationfilepathEditField.Value = uigetdir;
            if app.RadiationfilepathEditField.Value == 0
                app.RadiationfilepathEditField.Value = '';
            end
        end

        % Button pushed function: PathButton_4
        function PathButton_4Pushed(app, event)
            app.WindfilepathEditField.Value = uigetdir;
            if app.WindfilepathEditField.Value == 0
                app.WindfilepathEditField.Value = '';
            end
        end

        % Menu selected function: ExecuteMenu
        function ExecuteMenuSelected(app, event)
            StartMorphingFromApp(app)
        end

        % Value changed function: SavefilepathEditField
        function SavefilepathEditFieldValueChanged(app, event)
            value = app.SavefilepathEditField.Value;
            if app.Saveallvariableson1fileCheckBox.Value == 1
                
                app.TemperaturefilepathEditField.Enable = 1;
                app.TemperaturefilenameEditField.Enable = 1;
                app.RadiationfilepathEditField.Enable = 1;
                app.RadiationfilenameEditField.Enable = 1;
                app.WindfilepathEditField.Enable = 1;
                app.WindfilenameEditField.Enable = 1;
                
                app.TemperaturefilepathEditField.Value = value;
                app.RadiationfilepathEditField.Value = value;
                app.WindfilepathEditField.Value = value;
                
                app.TemperaturefilepathEditField.Enable = 0;
                app.TemperaturefilenameEditField.Enable = 0;
                app.RadiationfilepathEditField.Enable = 0;
                app.RadiationfilenameEditField.Enable = 0;
                app.WindfilepathEditField.Enable = 0;
                app.WindfilenameEditField.Enable = 0;
            end
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            if app.Saveallvariableson1fileCheckBox.Value == 1
                
                app.TemperaturefilepathEditField.Enable = 1;
                app.TemperaturefilenameEditField.Enable = 1;
                app.RadiationfilepathEditField.Enable = 1;
                app.RadiationfilenameEditField.Enable = 1;
                app.WindfilepathEditField.Enable = 1;
                app.WindfilenameEditField.Enable = 1;
                
                app.TemperaturefilenameEditField.Value = value;
                app.RadiationfilenameEditField.Value = value;
                app.WindfilenameEditField.Value = value;
                
                app.TemperaturefilepathEditField.Enable = 0;
                app.TemperaturefilenameEditField.Enable = 0;
                app.RadiationfilepathEditField.Enable = 0;
                app.RadiationfilenameEditField.Enable = 0;
                app.WindfilepathEditField.Enable = 0;
                app.WindfilenameEditField.Enable = 0;
            end
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            value = app.FileTypeDropDown.Value;
            if app.Saveallvariableson1fileCheckBox.Value == 1
                app.TemperatureFileTypeDropDown.Value = value;
                app.RadiationFileTypeDropDown.Value = value;
                app.WindFileTypeDropDown.Value = value;
            end
        end

        % Button pushed function: LoadFileButton_4
        function LoadFileButton_4Pushed(app, event)
            [fileMT, pathMT] = uigetfile;
            app.MonthlyChangeDataFileEditField.Value = string(pathMT) +string(fileMT) ;
            if fileMT == 0
                app.MonthlyChangeDataFileEditField.Value = '';
            end
        end

        % Value changed function: 
        % BaselineTemperaturefilelocationEditField
        function BaselineTemperaturefilelocationEditFieldValueChanged(app, event)
            value = app.BaselineTemperaturefilelocationEditField.Value;
            if app.UsethesamebaselinedataforallvariablesCheckBox.Value == 1
                app.BaselineGlobalRadiationFileEditField.Value = value;
                app.BaselineWindSpeedFileEditField.Value = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ExecuteMenu = uimenu(app.Menu);
            app.ExecuteMenu.MenuSelectedFcn = createCallbackFcn(app, @ExecuteMenuSelected, true);
            app.ExecuteMenu.Text = 'Execute';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.Text = 'Exit';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralDataTab = uitab(app.TabGroup);
            app.GeneralDataTab.Title = 'General Data';
            app.DateInformationPanel = uipanel(app.GeneralDataTab);
            app.DateInformationPanel.Title = 'Date Information';
            app.AddherethestartdarteofthebaselinedataLabel = uilabel(app.DateInformationPanel);
            app.AddherethestartdarteofthebaselinedataLabel.Text = 'Add here the start darte of the baseline data.';
            app.AddheretheenddateofthebaselinedataLabel = uilabel(app.DateInformationPanel);
            app.AddheretheenddateofthebaselinedataLabel.Text = 'Add here the end date of the baseline data.';
            app.StartDateDatePickerLabel = uilabel(app.DateInformationPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.DateInformationPanel);
            app.EndDateDatePickerLabel = uilabel(app.DateInformationPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.DateInformationPanel);
            app.Label_3 = uilabel(app.GeneralDataTab);
            app.Label_3.Text = 'This is a tool for creating a representative future climate data based on morphing, a statistical down-scaling method, ';
            app.Label_4 = uilabel(app.GeneralDataTab);
            app.Label_4.Text = 'with a baseline weather data and monthly, daily or long-term average data from climate models for outdoor temperature,';
            app.globalradiationandwindspeeddataLabel = uilabel(app.GeneralDataTab);
            app.globalradiationandwindspeeddataLabel.Text = 'global radiation and wind speed data.';
            app.ClimateChangeScenarioPanel = uipanel(app.GeneralDataTab);
            app.ClimateChangeScenarioPanel.Title = 'Climate Change Scenario';
            app.SelecttheusedclimatechangescenarioLabel = uilabel(app.ClimateChangeScenarioPanel);
            app.SelecttheusedclimatechangescenarioLabel.Text = 'Select the used climate change scenario';
            app.ClimatechangescenarioDropDownLabel = uilabel(app.ClimateChangeScenarioPanel);
            app.ClimatechangescenarioDropDownLabel.Text = 'Climate change scenario';
            app.ClimatechangescenarioDropDown = uidropdown(app.ClimateChangeScenarioPanel);
            app.ClimatechangescenarioDropDown.Items = {'RCP2.6', 'RCP4.5', 'RCP6.0', 'RCP8.5'};
            app.ClimatechangescenarioDropDown.Value = 'RCP2.6';
            app.LocationDataPanel = uipanel(app.GeneralDataTab);
            app.LocationDataPanel.Title = 'Location Data';
            app.Panel = uipanel(app.LocationDataPanel);
            app.Panel.Title = 'Add longitude and latitude information in case the original file is sorted like this (e.g. .nc -files)';
            app.LongitudeEditFieldLabel = uilabel(app.Panel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.Panel, 'numeric');
            app.LatitudeEditFieldLabel = uilabel(app.Panel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.Panel, 'numeric');
            app.AddthisinformationindegreesLabel = uilabel(app.Panel);
            app.AddthisinformationindegreesLabel.Text = 'Add this information in degrees.';
            app.AddthisinformationindegreesLabel_2 = uilabel(app.Panel);
            app.AddthisinformationindegreesLabel_2.Text = 'Add this information in degrees.';
            app.AddalsospacialerrorindegreestoincludemoredatapointsPanel = uipanel(app.LocationDataPanel);
            app.AddalsospacialerrorindegreestoincludemoredatapointsPanel.Title = 'Add also spacial error in degrees to include more datapoints';
            app.SpacialerrorEditFieldLabel = uilabel(app.AddalsospacialerrorindegreestoincludemoredatapointsPanel);
            app.SpacialerrorEditFieldLabel.Text = 'Spacial error';
            app.SpacialerrorEditField = uieditfield(app.AddalsospacialerrorindegreestoincludemoredatapointsPanel, 'numeric');
            app.AddthespacialerrorindegreesLabel = uilabel(app.AddalsospacialerrorindegreestoincludemoredatapointsPanel);
            app.AddthespacialerrorindegreesLabel.Text = 'Add the spacial error in degrees.';
            app.AddLocationNamePanel = uipanel(app.GeneralDataTab);
            app.AddLocationNamePanel.Title = 'Add Location Name';
            app.LocationNameEditFieldLabel = uilabel(app.AddLocationNamePanel);
            app.LocationNameEditFieldLabel.Text = 'Location Name';
            app.LocationNameEditField = uieditfield(app.AddLocationNamePanel, 'text');
            app.BaselineDataTab = uitab(app.TabGroup);
            app.BaselineDataTab.Title = 'Baseline Data';
            app.BaselineTemperaturePanel = uipanel(app.BaselineDataTab);
            app.BaselineTemperaturePanel.Title = 'Baseline Temperature';
            app.BaselineTemperaturefilelocationEditFieldLabel = uilabel(app.BaselineTemperaturePanel);
            app.BaselineTemperaturefilelocationEditFieldLabel.Text = 'Baseline Temperature file location:';
            app.BaselineTemperaturefilelocationEditField = uieditfield(app.BaselineTemperaturePanel, 'text');
            app.BaselineTemperaturefilelocationEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineTemperaturefilelocationEditFieldValueChanged, true);
            app.LoadFileButton = uibutton(app.BaselineTemperaturePanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.UsethesamebaselinedataforallvariablesCheckBox = uicheckbox(app.BaselineTemperaturePanel);
            app.UsethesamebaselinedataforallvariablesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsethesamebaselinedataforallvariablesCheckBoxValueChanged, true);
            app.UsethesamebaselinedataforallvariablesCheckBox.Text = 'Use the same baseline data for all variables';
            app.BaselineGlobalRadiationPanel = uipanel(app.BaselineDataTab);
            app.BaselineGlobalRadiationPanel.Title = 'Baseline Global Radiation';
            app.BaselineGlobalRadiationFileEditFieldLabel = uilabel(app.BaselineGlobalRadiationPanel);
            app.BaselineGlobalRadiationFileEditFieldLabel.Text = 'Baseline Global Radiation File:';
            app.BaselineGlobalRadiationFileEditField = uieditfield(app.BaselineGlobalRadiationPanel, 'text');
            app.LoadFileButton_2 = uibutton(app.BaselineGlobalRadiationPanel, 'push');
            app.LoadFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton_2Pushed, true);
            app.LoadFileButton_2.Text = 'Load File';
            app.BaselineWindSpeedPanel = uipanel(app.BaselineDataTab);
            app.BaselineWindSpeedPanel.Title = 'Baseline Wind Speed';
            app.BaselineWindSpeedFileEditFieldLabel = uilabel(app.BaselineWindSpeedPanel);
            app.BaselineWindSpeedFileEditFieldLabel.Text = 'Baseline Wind Speed File:';
            app.BaselineWindSpeedFileEditField = uieditfield(app.BaselineWindSpeedPanel, 'text');
            app.LoadFileButton_3 = uibutton(app.BaselineWindSpeedPanel, 'push');
            app.LoadFileButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton_3Pushed, true);
            app.LoadFileButton_3.Text = 'Load File';
            app.ClimateChangeDataTab = uitab(app.TabGroup);
            app.ClimateChangeDataTab.Title = 'Climate Change Data';
            app.TemperaturePanel = uipanel(app.ClimateChangeDataTab);
            app.TemperaturePanel.Title = 'Temperature ';
            app.MorphingOptionsPanel = uipanel(app.TemperaturePanel);
            app.MorphingOptionsPanel.Title = 'Morphing Options';
            app.SelectmorphingmethodforTemperaturefileButtonGroup = uibuttongroup(app.MorphingOptionsPanel);
            app.SelectmorphingmethodforTemperaturefileButtonGroup.Title = 'Select morphing method for Temperature file';
            app.ShiftButton = uiradiobutton(app.SelectmorphingmethodforTemperaturefileButtonGroup);
            app.ShiftButton.Text = 'Shift';
            app.ShiftButton.Value = true;
            app.StretchButton = uiradiobutton(app.SelectmorphingmethodforTemperaturefileButtonGroup);
            app.StretchButton.Text = 'Stretch';
            app.ShiftandstretchButton = uiradiobutton(app.SelectmorphingmethodforTemperaturefileButtonGroup);
            app.ShiftandstretchButton.Text = 'Shift and stretch';
            app.SelecttypeofusedclimatechangedataButtonGroup = uibuttongroup(app.MorphingOptionsPanel);
            app.SelecttypeofusedclimatechangedataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelecttypeofusedclimatechangedataButtonGroupSelectionChanged, true);
            app.SelecttypeofusedclimatechangedataButtonGroup.Title = 'Select type of used climate change data';
            app.OnlymonthlyButton = uiradiobutton(app.SelecttypeofusedclimatechangedataButtonGroup);
            app.OnlymonthlyButton.Text = 'Only monthly';
            app.OnlymonthlyButton.Value = true;
            app.StdDailyButton = uiradiobutton(app.SelecttypeofusedclimatechangedataButtonGroup);
            app.StdDailyButton.Text = 'StdDaily';
            app.MaxMinButton = uiradiobutton(app.SelecttypeofusedclimatechangedataButtonGroup);
            app.MaxMinButton.Text = 'MaxMin';
            app.SelectClimateChangeDataforTemperaturemorphingPanel = uipanel(app.TemperaturePanel);
            app.SelectClimateChangeDataforTemperaturemorphingPanel.Title = 'Select Climate Change Data for Temperature morphing';
            app.MonthlyChangeDataFileEditFieldLabel = uilabel(app.SelectClimateChangeDataforTemperaturemorphingPanel);
            app.MonthlyChangeDataFileEditFieldLabel.Text = 'Monthly Change Data File:';
            app.MonthlyChangeDataFileEditField = uieditfield(app.SelectClimateChangeDataforTemperaturemorphingPanel, 'text');
            app.LoadFileButton_4 = uibutton(app.SelectClimateChangeDataforTemperaturemorphingPanel, 'push');
            app.LoadFileButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton_4Pushed, true);
            app.LoadFileButton_4.Text = 'Load File';
            app.DataBaseTypeDropDownLabel = uilabel(app.SelectClimateChangeDataforTemperaturemorphingPanel);
            app.DataBaseTypeDropDownLabel.Text = 'DataBaseType';
            app.DataBaseTypeDropDown = uidropdown(app.SelectClimateChangeDataforTemperaturemorphingPanel);
            app.DataBaseTypeDropDown.Items = {'LongTermMean', 'Paituli', 'ECEM'};
            app.DataBaseTypeDropDown.Value = 'LongTermMean';
            app.FutureDailyMeantemperatureDatafileEditFieldLabel = uilabel(app.SelectClimateChangeDataforTemperaturemorphingPanel);
            app.FutureDailyMeantemperatureDatafileEditFieldLabel.Text = 'Future Daily Mean temperature Data file:';
            app.FutureDailyMeantemperatureDatafileEditField = uieditfield(app.SelectClimateChangeDataforTemperaturemorphingPanel, 'text');
            app.LoadFileButton_5 = uibutton(app.SelectClimateChangeDataforTemperaturemorphingPanel, 'push');
            app.LoadFileButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton_5Pushed, true);
            app.LoadFileButton_5.Text = 'Load File';
            app.FutureDailyMaxtemperatureDatafileEditFieldLabel = uilabel(app.SelectClimateChangeDataforTemperaturemorphingPanel);
            app.FutureDailyMaxtemperatureDatafileEditFieldLabel.Text = 'Future Daily Max temperature Data file:';
            app.FutureDailyMaxtemperatureDatafileEditField = uieditfield(app.SelectClimateChangeDataforTemperaturemorphingPanel, 'text');
            app.LoadFileButton_6 = uibutton(app.SelectClimateChangeDataforTemperaturemorphingPanel, 'push');
            app.LoadFileButton_6.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton_6Pushed, true);
            app.LoadFileButton_6.Text = 'Load File';
            app.FutureDailyMintemperatureDatafileEditFieldLabel = uilabel(app.SelectClimateChangeDataforTemperaturemorphingPanel);
            app.FutureDailyMintemperatureDatafileEditFieldLabel.Text = 'Future Daily Min temperature Data file:';
            app.FutureDailyMintemperatureDatafileEditField = uieditfield(app.SelectClimateChangeDataforTemperaturemorphingPanel, 'text');
            app.LoadFileButton_7 = uibutton(app.SelectClimateChangeDataforTemperaturemorphingPanel, 'push');
            app.LoadFileButton_7.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton_7Pushed, true);
            app.LoadFileButton_7.Text = 'Load File';
            app.GlobalRadiationWindSpeedPanel = uipanel(app.ClimateChangeDataTab);
            app.GlobalRadiationWindSpeedPanel.Title = 'Global Radiation & Wind Speed';
            app.MorphingOptionsPanel_2 = uipanel(app.GlobalRadiationWindSpeedPanel);
            app.MorphingOptionsPanel_2.Title = 'Morphing Options';
            app.SelectmorphingmethodforGlobalRadiationfileButtonGroup = uibuttongroup(app.MorphingOptionsPanel_2);
            app.SelectmorphingmethodforGlobalRadiationfileButtonGroup.Title = 'Select morphing method for Global Radiation file';
            app.StretchButton_3 = uiradiobutton(app.SelectmorphingmethodforGlobalRadiationfileButtonGroup);
            app.StretchButton_3.Text = 'Stretch';
            app.StretchButton_3.Value = true;
            app.SelectmorphingmethodforWindSpeedButtonGroup = uibuttongroup(app.MorphingOptionsPanel_2);
            app.SelectmorphingmethodforWindSpeedButtonGroup.Title = 'Select morphing method for Wind Speed';
            app.StretchButton_2 = uiradiobutton(app.SelectmorphingmethodforWindSpeedButtonGroup);
            app.StretchButton_2.Text = 'Stretch';
            app.StretchButton_2.Value = true;
            app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel = uipanel(app.GlobalRadiationWindSpeedPanel);
            app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel.Title = 'Select Climate Change Data for Global Radiation and Wind Speed';
            app.MonthlyGlobalRadiationChangeEditFieldLabel = uilabel(app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel);
            app.MonthlyGlobalRadiationChangeEditFieldLabel.Text = 'Monthly Global Radiation Change:';
            app.MonthlyGlobalRadiationChangeEditField = uieditfield(app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel, 'text');
            app.LoadFileButton_8 = uibutton(app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel, 'push');
            app.LoadFileButton_8.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton_8Pushed, true);
            app.LoadFileButton_8.Text = 'Load File';
            app.MonthlyWindSpeedChangeEditFieldLabel = uilabel(app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel);
            app.MonthlyWindSpeedChangeEditFieldLabel.Text = 'Monthly Wind Speed Change:';
            app.MonthlyWindSpeedChangeEditField = uieditfield(app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel, 'text');
            app.LoadFileButton_9 = uibutton(app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel, 'push');
            app.LoadFileButton_9.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton_9Pushed, true);
            app.LoadFileButton_9.Text = 'Load File';
            app.DataBaseTypeRadiationWindDropDownLabel = uilabel(app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel);
            app.DataBaseTypeRadiationWindDropDownLabel.Text = 'DataBase Type Radiation & Wind';
            app.DataBaseTypeRadiationWindDropDown = uidropdown(app.SelectClimateChangeDataforGlobalRadiationandWindSpeedPanel);
            app.DataBaseTypeRadiationWindDropDown.Items = {'LongTermMean', 'Relative Change', 'Monthly'};
            app.DataBaseTypeRadiationWindDropDown.Value = 'LongTermMean';
            app.ClimateChangeInfoTab = uitab(app.TabGroup);
            app.ClimateChangeInfoTab.Title = 'Climate Change Info';
            app.GeneralInfoOnUsedTimeperiodsPanel = uipanel(app.ClimateChangeInfoTab);
            app.GeneralInfoOnUsedTimeperiodsPanel.Title = 'General Info On Used Time periods';
            app.AveragePeriodEditFieldLabel = uilabel(app.GeneralInfoOnUsedTimeperiodsPanel);
            app.AveragePeriodEditFieldLabel.Text = 'Average Period';
            app.AveragePeriodEditField = uieditfield(app.GeneralInfoOnUsedTimeperiodsPanel, 'numeric');
            app.ReferenceYearEditFieldLabel = uilabel(app.GeneralInfoOnUsedTimeperiodsPanel);
            app.ReferenceYearEditFieldLabel.Text = 'Reference Year';
            app.ReferenceYearEditField = uieditfield(app.GeneralInfoOnUsedTimeperiodsPanel, 'numeric');
            app.MorphingYearEditFieldLabel = uilabel(app.GeneralInfoOnUsedTimeperiodsPanel);
            app.MorphingYearEditFieldLabel.Text = 'Morphing Year';
            app.MorphingYearEditField = uieditfield(app.GeneralInfoOnUsedTimeperiodsPanel, 'numeric');
            app.MonthlyTemperatureStartYearEditFieldLabel = uilabel(app.GeneralInfoOnUsedTimeperiodsPanel);
            app.MonthlyTemperatureStartYearEditFieldLabel.Text = 'Monthly Temperature Start Year';
            app.MonthlyTemperatureStartYearEditField = uieditfield(app.GeneralInfoOnUsedTimeperiodsPanel, 'numeric');
            app.DailyTemperatureStartYearEditFieldLabel = uilabel(app.GeneralInfoOnUsedTimeperiodsPanel);
            app.DailyTemperatureStartYearEditFieldLabel.Text = 'Daily Temperature Start Year';
            app.DailyTemperatureStartYearEditField = uieditfield(app.GeneralInfoOnUsedTimeperiodsPanel, 'numeric');
            app.RadiationChangeStartYearEditFieldLabel = uilabel(app.GeneralInfoOnUsedTimeperiodsPanel);
            app.RadiationChangeStartYearEditFieldLabel.Text = 'Radiation Change Start Year';
            app.RadiationChangeStartYearEditField = uieditfield(app.GeneralInfoOnUsedTimeperiodsPanel, 'numeric');
            app.WindChangeStartYearEditFieldLabel = uilabel(app.GeneralInfoOnUsedTimeperiodsPanel);
            app.WindChangeStartYearEditFieldLabel.Text = 'Wind Change Start Year';
            app.WindChangeStartYearEditField = uieditfield(app.GeneralInfoOnUsedTimeperiodsPanel, 'numeric');
            app.GeneralInfoforSavingDataPanel = uipanel(app.ClimateChangeInfoTab);
            app.GeneralInfoforSavingDataPanel.Title = 'General Info for Saving Data';
            app.Saveallvariableson1fileCheckBox = uicheckbox(app.GeneralInfoforSavingDataPanel);
            app.Saveallvariableson1fileCheckBox.ValueChangedFcn = createCallbackFcn(app, @Saveallvariableson1fileCheckBoxValueChanged, true);
            app.Saveallvariableson1fileCheckBox.Text = 'Save all variables on 1 file';
            app.SavefilepathEditFieldLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.SavefilepathEditFieldLabel.Text = 'Save file path';
            app.SavefilepathEditField = uieditfield(app.GeneralInfoforSavingDataPanel, 'text');
            app.SavefilepathEditField.ValueChangedFcn = createCallbackFcn(app, @SavefilepathEditFieldValueChanged, true);
            app.PathButton = uibutton(app.GeneralInfoforSavingDataPanel, 'push');
            app.PathButton.ButtonPushedFcn = createCallbackFcn(app, @PathButtonPushed, true);
            app.PathButton.Text = 'Path';
            app.FileNameEditFieldLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GeneralInfoforSavingDataPanel, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.TemperaturefilepathEditFieldLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.TemperaturefilepathEditFieldLabel.Text = 'Temperature file path';
            app.TemperaturefilepathEditField = uieditfield(app.GeneralInfoforSavingDataPanel, 'text');
            app.PathButton_2 = uibutton(app.GeneralInfoforSavingDataPanel, 'push');
            app.PathButton_2.ButtonPushedFcn = createCallbackFcn(app, @PathButton_2Pushed, true);
            app.PathButton_2.Text = 'Path';
            app.TemperaturefilenameEditFieldLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.TemperaturefilenameEditFieldLabel.Text = 'Temperature file name';
            app.TemperaturefilenameEditField = uieditfield(app.GeneralInfoforSavingDataPanel, 'text');
            app.TypeDropDownLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.FileTypeDropDown = uidropdown(app.GeneralInfoforSavingDataPanel);
            app.FileTypeDropDown.Items = {'.mat', '.csv', '.epw'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = '.mat';
            app.TypeDropDown_2Label = uilabel(app.GeneralInfoforSavingDataPanel);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TemperatureFileTypeDropDown = uidropdown(app.GeneralInfoforSavingDataPanel);
            app.TemperatureFileTypeDropDown.Items = {'.mat', '.csv', '.epw'};
            app.TemperatureFileTypeDropDown.Value = '.mat';
            app.RadiationfilepathEditFieldLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.RadiationfilepathEditFieldLabel.Text = 'Radiation file path';
            app.RadiationfilepathEditField = uieditfield(app.GeneralInfoforSavingDataPanel, 'text');
            app.PathButton_3 = uibutton(app.GeneralInfoforSavingDataPanel, 'push');
            app.PathButton_3.ButtonPushedFcn = createCallbackFcn(app, @PathButton_3Pushed, true);
            app.PathButton_3.Text = 'Path';
            app.RadiationfilenameEditFieldLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.RadiationfilenameEditFieldLabel.Text = 'Radiation file name';
            app.RadiationfilenameEditField = uieditfield(app.GeneralInfoforSavingDataPanel, 'text');
            app.TypeDropDown_3Label = uilabel(app.GeneralInfoforSavingDataPanel);
            app.TypeDropDown_3Label.Text = 'Type';
            app.RadiationFileTypeDropDown = uidropdown(app.GeneralInfoforSavingDataPanel);
            app.RadiationFileTypeDropDown.Items = {'.mat', '.csv', '.epw'};
            app.RadiationFileTypeDropDown.Value = '.mat';
            app.WindfilepathEditFieldLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.WindfilepathEditFieldLabel.Text = 'Wind file path';
            app.WindfilepathEditField = uieditfield(app.GeneralInfoforSavingDataPanel, 'text');
            app.PathButton_4 = uibutton(app.GeneralInfoforSavingDataPanel, 'push');
            app.PathButton_4.ButtonPushedFcn = createCallbackFcn(app, @PathButton_4Pushed, true);
            app.PathButton_4.Text = 'Path';
            app.WindfilenameEditFieldLabel = uilabel(app.GeneralInfoforSavingDataPanel);
            app.WindfilenameEditFieldLabel.Text = 'Wind file name';
            app.WindfilenameEditField = uieditfield(app.GeneralInfoforSavingDataPanel, 'text');
            app.TypeDropDown_4Label = uilabel(app.GeneralInfoforSavingDataPanel);
            app.TypeDropDown_4Label.Text = 'Type';
            app.WindFileTypeDropDown = uidropdown(app.GeneralInfoforSavingDataPanel);
            app.WindFileTypeDropDown.Items = {'.mat', '.csv', '.epw'};
            app.WindFileTypeDropDown.Value = '.mat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MorphingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end